classdef MiniProyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PANELDECONTROLMINIPROYECTOLabel  matlab.ui.control.Label
        DatosaGraficarNEditFieldLabel  matlab.ui.control.Label
        DatosaGraficarNEditField       matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EstabilizaraEditFieldLabel     matlab.ui.control.Label
        EstabilizaraEditField          matlab.ui.control.NumericEditField
        SAVEDATAButton                 matlab.ui.control.Button
        DatosenBrutoMPU6050TextAreaLabel_3  matlab.ui.control.Label
        AceleracinxLabel               matlab.ui.control.Label
        AceleracinxEditField           matlab.ui.control.EditField
        AceleracinyLabel               matlab.ui.control.Label
        AceleracinyEditField           matlab.ui.control.EditField
        AceleracinzLabel               matlab.ui.control.Label
        AceleracinzEditField           matlab.ui.control.EditField
        GiroxLabel                     matlab.ui.control.Label
        GiroxEditField                 matlab.ui.control.EditField
        GiroyLabel                     matlab.ui.control.Label
        GiroyEditField                 matlab.ui.control.EditField
        GirozLabel                     matlab.ui.control.Label
        GirozEditField                 matlab.ui.control.EditField
        nguloBalancnEditFieldLabel     matlab.ui.control.Label
        nguloBalancnEditField          matlab.ui.control.EditField
        SENDDATAButton                 matlab.ui.control.Button
        kPEditFieldLabel               matlab.ui.control.Label
        kPEditField                    matlab.ui.control.NumericEditField
        kIEditFieldLabel               matlab.ui.control.Label
        kIEditField                    matlab.ui.control.NumericEditField
        kDEditFieldLabel               matlab.ui.control.Label
        kDEditField                    matlab.ui.control.NumericEditField
        ConstantesControladorPIDLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)  %Declaración de Variables Globales
        Property % Description
        giro_x = 0;  %Variables para guardar los datos recibidos por UART
        giro_y = 0;
        giro_z = 0;
        acel_x = 0;
        acel_y = 0;
        acel_z = 0;
        angu_y = 0;
%         flag_send = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Constantes para Graficar
            N = 1000;               % N inicial 
            t = linspace(0,N,N)';   % Ajustar la gráfica para N datos
            K = 10;

            %Configuración de Puerto y BaudRate
            ser = serialport('COM6',115200);
            
            %Iniciamos un Plot en cero para luego actualizar con los datos
            %recibidos
            app.UIAxes,clf;
            h7 = plot(app.UIAxes,t,zeros(N,1));
            xlim(app.UIAxes,[0,t(end)]);
            ylim(app.UIAxes,[-90,90]);
            buffer = zeros(K,1);
            k = 1;

            % Ciclo While para actualizar datos continuamente
            while (1)                                
                for n = 1:N
                    %Revisar si hay datos para enviar
                    
%                     if app.flag_send.Value == True
%                         salida = app.EstabilizaraEditField.Value;
                    %writeline (ser, app.EstabilizaraEditField.Value, "unit8");
%                         app.flag_send.Value = False;
%                     end
                    
                    %Actualización y gráfica de datos
                    data = readline(ser);           %Lectura de datos
                    data_split = split(data," ");   %Separación de string
                    app.giro_x = data_split(1);
                    app.giro_y = data_split(2);
                    app.giro_z = data_split(3);
                    app.acel_x = data_split(4);
                    app.acel_y = data_split(5);
                    app.acel_z = data_split(6);
                    app.angu_y = data_split(7);
                    
                    %Desplegar datos en Pantalla de forma numércia
                    app.AceleracinxEditField.Value = app.acel_x; 
                    app.AceleracinyEditField.Value = app.acel_y;
                    app.AceleracinzEditField.Value = app.acel_z;
                    app.GiroxEditField.Value = app.giro_x;
                    app.GiroyEditField.Value = app.giro_y;
                    app.GirozEditField.Value = app.giro_z;
                    app.nguloBalancnEditField.Value = app.angu_y;
                   
                    %Conversión de string para graficar
                    y(n,1) = str2double(app.angu_y);
                    drawnow limitrate
                    
                       buffer(k) = y(n);
                    
                    if(k == K)
                        h7.YData((n-K+1):n) = buffer;   % Asume que K es factor de N. De lo contrario,
                                                        % hay que hacer ajustes adicionales.
                        drawnow limitrate
                        k = 1;
                    else
                        k = k + 1;    
                    end
                end
                %Actualizar datos de la gráfica
                N = app.DatosaGraficarNEditField.Value;
                t = linspace(0,N,N)';
                K = 10;
                h7 = plot(app.UIAxes,t,zeros(N,1));
                xlim(app.UIAxes,[0,t(end)]);
                ylim(app.UIAxes,[-90,90]);
                buffer = zeros(K,1);
            end
    
        end

        % Callback function
        function CONTROLADORSwitchValueChanged(app, event)
            value_switch = app.CONTROLADORSwitch.Value;
            if value_switch == ("On")
                app.kPEditField.Enable = false;
                app.kIEditField.Enable = false;
                app.kDEditField.Enable = false;
                app.EstabilizaraEditField.Enable = false;
            end
       
           if value_switch == ("Off")
                app.kPEditField.Enable = true;
                app.kIEditField.Enable = true;
                app.kDEditField.Enable = true;
                app.EstabilizaraEditField.Enable = true;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            %Generación de Archivo .txt con datos actuales
%             name = ('Reporte1.txt');
%             fileID = fopen(name,'w');
            [usfile, uspath] = uiputfile(".txt");
            if usfile == 0
                % User clicked the Cancel button.
                return;
                
            end
            
            fullFileName = fullfile(uspath, usfile);

            fileID = fopen(usfile,'w');
            fprintf(fileID, 'Universidad del Valle de Guatemala\nDiseño e Innovación en Ingienería\nSección 20\n\nKatharine Senn - 18012\n\n');
            fprintf(fileID, 'Reporte de Estado Actual Balancín\n\n');
            fprintf(fileID, 'Aceleración en X: %i \n', str2double(app.AceleracinxEditField.Value));
            fprintf(fileID, 'Aceleración en Y: %i \n', str2double(app.AceleracinyEditField.Value));
            fprintf(fileID, 'Aceleración en Z: %i \n \n', str2double(app.AceleracinzEditField.Value));
            fprintf(fileID, 'Giro sobre Eje X: %i \n', str2double(app.GiroxEditField.Value));
            fprintf(fileID, 'Giro sobre Eje Y: %i \n', str2double(app.GiroyEditField.Value));
            fprintf(fileID, 'Giro sobre Eje Z: %i \n \n', str2double(app.GirozEditField.Value));
            fprintf(fileID, 'Ángulo del Balancín: %i \n', str2double(app.nguloBalancnEditField.Value));
            fclose(fileID);
        end

        % Button pushed function: SENDDATAButton
        function SENDDATAButtonPushed(app, event)
            %data_out = writeline(ser); 
%             salida = app.EstabilizaraEditField.Value;
%             write (ser, salida, "char");
%             app.flag_send.Value = True;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PANELDECONTROLMINIPROYECTOLabel = uilabel(app.UIFigure);
            app.PANELDECONTROLMINIPROYECTOLabel.Text = 'PANEL DE CONTROL (MINI PROYECTO)';
            app.DatosaGraficarNEditFieldLabel = uilabel(app.UIFigure);
            app.DatosaGraficarNEditFieldLabel.Text = 'Datos a Graficar (N):';
            app.DatosaGraficarNEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatosaGraficarNEditField.Limits = [1 10000];
            app.DatosaGraficarNEditField.Value = 1000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_________________________________________________________________';
            app.EstabilizaraEditFieldLabel = uilabel(app.UIFigure);
            app.EstabilizaraEditFieldLabel.Text = 'Estabilizar a:';
            app.EstabilizaraEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstabilizaraEditField.Limits = [-60 60];
            app.SAVEDATAButton = uibutton(app.UIFigure, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.DatosenBrutoMPU6050TextAreaLabel_3 = uilabel(app.UIFigure);
            app.DatosenBrutoMPU6050TextAreaLabel_3.Text = 'Datos en Bruto: MPU6050';
            app.AceleracinxLabel = uilabel(app.UIFigure);
            app.AceleracinxLabel.Text = 'Aceleración x:';
            app.AceleracinxEditField = uieditfield(app.UIFigure, 'text');
            app.AceleracinxEditField.Enable = 'off';
            app.AceleracinxEditField.Value = '0';
            app.AceleracinyLabel = uilabel(app.UIFigure);
            app.AceleracinyLabel.Text = 'Aceleración y:';
            app.AceleracinyEditField = uieditfield(app.UIFigure, 'text');
            app.AceleracinyEditField.Enable = 'off';
            app.AceleracinyEditField.Value = '0';
            app.AceleracinzLabel = uilabel(app.UIFigure);
            app.AceleracinzLabel.Text = 'Aceleración z:';
            app.AceleracinzEditField = uieditfield(app.UIFigure, 'text');
            app.AceleracinzEditField.Enable = 'off';
            app.AceleracinzEditField.Value = '0';
            app.GiroxLabel = uilabel(app.UIFigure);
            app.GiroxLabel.Text = 'Giro x:';
            app.GiroxEditField = uieditfield(app.UIFigure, 'text');
            app.GiroxEditField.Enable = 'off';
            app.GiroxEditField.Value = '0';
            app.GiroyLabel = uilabel(app.UIFigure);
            app.GiroyLabel.Text = 'Giro y:';
            app.GiroyEditField = uieditfield(app.UIFigure, 'text');
            app.GiroyEditField.Enable = 'off';
            app.GiroyEditField.Value = '0';
            app.GirozLabel = uilabel(app.UIFigure);
            app.GirozLabel.Text = 'Giro z:';
            app.GirozEditField = uieditfield(app.UIFigure, 'text');
            app.GirozEditField.Enable = 'off';
            app.GirozEditField.Value = '0';
            app.nguloBalancnEditFieldLabel = uilabel(app.UIFigure);
            app.nguloBalancnEditFieldLabel.Text = 'Ángulo Balancín';
            app.nguloBalancnEditField = uieditfield(app.UIFigure, 'text');
            app.nguloBalancnEditField.Enable = 'off';
            app.nguloBalancnEditField.Value = '0';
            app.SENDDATAButton = uibutton(app.UIFigure, 'push');
            app.SENDDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SENDDATAButtonPushed, true);
            app.SENDDATAButton.Text = 'SEND DATA';
            app.kPEditFieldLabel = uilabel(app.UIFigure);
            app.kPEditFieldLabel.Text = 'kP:';
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kPEditField.Value = 5;
            app.kIEditFieldLabel = uilabel(app.UIFigure);
            app.kIEditFieldLabel.Text = 'kI:';
            app.kIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditFieldLabel = uilabel(app.UIFigure);
            app.kDEditFieldLabel.Text = 'kD:';
            app.kDEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditField.Value = 2;
            app.ConstantesControladorPIDLabel = uilabel(app.UIFigure);
            app.ConstantesControladorPIDLabel.Text = 'Constantes Controlador PID';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ángulo Balancín')
            xlabel(app.UIAxes, 'N')
            ylabel(app.UIAxes, 'θ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 60];
            app.UIAxes.XTickLabel = {'0'};
            app.UIAxes.YTick = [-90 0 90];
            app.UIAxes.YTickLabel = {'-90'; '0'; '90'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiniProyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end